classdef GestureDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResultEditField        matlab.ui.control.EditField
        ResultEditFieldLabel   matlab.ui.control.Label
        GESTUREDETECTIONLabel  matlab.ui.control.Label
        DetectGestureButton    matlab.ui.control.Button
        CaptureImageButton     matlab.ui.control.Button
        LiveCamButton          matlab.ui.control.Button
        SpeakButton            matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam; %webcam
        gesture; %detected gesture
        net; %Trained nueral network
        inputFrame; %Captured Image
        segmentFrame; %Segmented Iamge
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LiveCamButton
        function LiveCamButtonPushed(app, event)
            app.cam = webcam;
            snap = snapshot(app.cam);
            im = image(app.UIAxes, zeros(size(snap),'uint8'));
            axis(app.UIAxes,'image');
            preview(app.cam,im);
        end

        % Button pushed function: CaptureImageButton
        function CaptureImageButtonPushed(app, event)
            app.cam = webcam;
            x=0;
            y=0;
            height=300;
            width=300;
            bboxes=[x y height width];
            frame = snapshot(app.cam);
            %IFaces = insertObjectAnnotation(frame,'rectangle',bboxes,'Processing Area');
            croppedFrame=imcrop(frame,bboxes);
            app.inputFrame=imresize(croppedFrame,[227 227]);
            imshow(app.inputFrame,'Parent',app.UIAxes2);
        end

        % Button pushed function: DetectGestureButton
        function DetectGestureButtonPushed(app, event)
            load myNET1.mat;
            label=classify(myNet1,app.inputFrame);
            app.gesture = char(label);
            app.ResultEditField.Value = app.gesture;
            %title(char(label));
            %drawnow;
        end

        % Button pushed function: SpeakButton
        function SpeakButtonPushed(app, event)
            NET.addAssembly('System.Speech');
            obj = System.Speech.Synthesis.SpeechSynthesizer;
            obj.Volume = 100;
            Speak(obj, app.gesture);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'LIVE CAM')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SpeakButton = uibutton(app.UIFigure, 'push');
            app.SpeakButton.ButtonPushedFcn = createCallbackFcn(app, @SpeakButtonPushed, true);
            app.SpeakButton.Text = {'Speak'; ''};
            app.LiveCamButton = uibutton(app.UIFigure, 'push');
            app.LiveCamButton.ButtonPushedFcn = createCallbackFcn(app, @LiveCamButtonPushed, true);
            app.LiveCamButton.Text = 'Live Cam';
            app.CaptureImageButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureImageButtonPushed, true);
            app.CaptureImageButton.Text = 'Capture Image';
            app.DetectGestureButton = uibutton(app.UIFigure, 'push');
            app.DetectGestureButton.ButtonPushedFcn = createCallbackFcn(app, @DetectGestureButtonPushed, true);
            app.DetectGestureButton.Text = 'Detect Gesture';
            app.GESTUREDETECTIONLabel = uilabel(app.UIFigure);
            app.GESTUREDETECTIONLabel.Text = 'GESTURE DETECTION';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GestureDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end